classdef RangeScanner_APP < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                  matlab.ui.Figure
        TimestampEditField        matlab.ui.control.EditField
        TimestampEditFieldLabel   matlab.ui.control.Label
        SerialPortEditField       matlab.ui.control.EditField
        SerialPortEditFieldLabel  matlab.ui.control.Label
        BaudRateDropDown          matlab.ui.control.DropDown
        BaudRateDropDownLabel     matlab.ui.control.Label
        StopButton                matlab.ui.control.Button
        StartButton               matlab.ui.control.Button
        BaseLabel                 matlab.ui.control.Label
    end

    
    properties (Access = public)
        dat_angle   % data angle
        dat_sonic   % data sonic
        ser         % serial object
%         timestamp   % timestamp for incomming data
    end
    
    properties (Access = private)
        Pax % Polar axes
        MAX_SIZE  % max size of the logged data
        RUN_FLAG 
    end
    
    methods (Access = private)
        
%         function updateplot(app)
%             
            % Define theta. Get a and b
%             theta = 0:pi/1800:2*pi;
%             MAX_SIZE = app.DbSizeEditField.Value; % max size of the logged data
%             
           % create a frame for the plot
%             pl = polarscatter(dat_angle, dat_sonic, 'filled');
%             
%             % Set line color
%             pl.Color = app.SerialPortDropDown.Value;
%             pl.XDataSource = 'dat_angle';
%             pl.YDataSource = 'dat_sonic';
%         end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % max size of the logged data
            app.MAX_SIZE = 1000;
            
            %limit of the plot
            THETA_MIN = 0;  % deg
            THETA_MAX = 180;% deg
            RHO_MIN = 0;    % m
            RHO_MAX = 100;  % m
            
            set(app.StopButton,'Enable','off'); % disable stop button
            
            % dummy arrays for data storage
            app.dat_angle = zeros(1,app.MAX_SIZE);
            app.dat_sonic = zeros(1,app.MAX_SIZE);
%             app.timestamp = NaN(1,app.MAX_SIZE);
            % Create polar axes and position it in pixels
            app.Pax = polaraxes(app.UIFigure);
            
            % create a frame for the plot
            pl = polarscatter(app.Pax, app.dat_angle, app.dat_sonic, 300, 'filled');
            % link the plot with variables that will be changed real time
            pl.XDataSource = 'app.dat_angle';
            pl.YDataSource = 'app.dat_sonic';
            
            app.Pax.Units = 'pixels';
            app.Pax.Position = [260 55 230 230];
            app.Pax.ThetaLim = [THETA_MIN THETA_MAX];
            app.Pax.RLim = [RHO_MIN RHO_MAX];
            app.Pax.RTick = [0 10 20 30 40 50 60 70 80 90 100];
            
            
        end

        % Button pushed function: StartButton
        function InitConnection(app, event)
            set(app.StartButton,'Enable','off'); % disable start button
            set(app.StopButton,'Enable','off'); % disable stop button
            % define the pattern
            pat_reg = "[0-9].?[0-9]+"; %[0-9].?[0-9]+
            
            % init the serial port
            app.ser = serialport(app.SerialPortEditField.Value,str2double(app.BaudRateDropDown.Value),'Timeout',2);
%             ser = serialport("COM3",9600,'Timeout',2);
            % send start signal to the MCU
            pause(2); % ease the time for the serial connection to ready
            write(app.ser,'\n',"uint8"); % start command
            % show timestamp
            app.TimestampEditField.Value = datestr(now,'HH:mm:ss'); % display the timestamp
            app.TimestampEditField.Visible = true;
            app.TimestampEditFieldLabel.Visible = true;
            app.RUN_FLAG = true;
            set(app.StopButton,'Enable','on'); % enable stop button
            
            % start reading data from MCU
            for n = 1:app.MAX_SIZE
                if  app.RUN_FLAG
                    % read data in
                    flush(app.ser,"input"); raw_dat = readline(app.ser); % wait and read the incomming data
                    app.TimestampEditField.Value = datestr(now,'HH:mm:ss'); % display the timestamp
                    % app.timestamp(n) = datetime("now",'InputFormat', 'HH:mm:ss'); % record the timestamp
                    
                    % process the given data
                    buf_dat = regexp(raw_dat,pat_reg,'match'); %   buf_dat = extract(dat_raw,pat);
                    app.dat_angle(n) = deg2rad(str2double(buf_dat(1)));
                    app.dat_sonic(n) = str2double(buf_dat(2));
                    
                    % update the plot
                    refreshdata(app.Pax, 'caller');
                    drawnow;
                else
                    return;
                end
            
            end
            StopConnection(app);
            
        end

        % Button pushed function: StopButton
        function StopConnection(app, event)
            set(app.StopButton,'Enable','off'); % disable stop button
            write(app.ser,'kkk',"uint8"); % kill command
            app.RUN_FLAG = false;
            % reset the plot
            app.dat_angle = zeros(1,app.MAX_SIZE);
            app.dat_sonic = zeros(1,app.MAX_SIZE);
            % update the plot
            refreshdata(app.Pax, 'caller');
            delete(app.ser);
            drawnow;
            set(app.StartButton,'Enable','on');
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'HCSR04 2D Range Detector - Group 4';
            app.BaseLabel = uilabel(app.UIFigure);
            app.BaseLabel.Text = 'HCSR04 2D range detector - Group 4';
            app.StartButton = uibutton(app.UIFigure, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @InitConnection, true);
            app.StartButton.Text = 'Start';
            app.StopButton = uibutton(app.UIFigure, 'push');
            app.StopButton.ButtonPushedFcn = createCallbackFcn(app, @StopConnection, true);
            app.StopButton.Text = 'Stop';
            app.BaudRateDropDownLabel = uilabel(app.UIFigure);
            app.BaudRateDropDownLabel.Text = 'Baud Rate';
            app.BaudRateDropDown = uidropdown(app.UIFigure);
            app.BaudRateDropDown.Items = {'115200', '9600'};
            app.BaudRateDropDown.Value = '115200';
            app.SerialPortEditFieldLabel = uilabel(app.UIFigure);
            app.SerialPortEditFieldLabel.Text = 'Serial Port';
            app.SerialPortEditField = uieditfield(app.UIFigure, 'text');
            app.SerialPortEditField.Value = 'COM3';
            app.TimestampEditFieldLabel = uilabel(app.UIFigure);
            app.TimestampEditFieldLabel.Visible = 'off';
            app.TimestampEditFieldLabel.Text = 'Timestamp';
            app.TimestampEditField = uieditfield(app.UIFigure, 'text');
            app.TimestampEditField.Editable = 'off';
            app.TimestampEditField.Visible = 'off';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = RangeScanner_APP

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end